classdef DBProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBProcessorUIFigure           matlab.ui.Figure
        MATLABDATAPROCESSINGSYSTEMLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        INPUTTab                      matlab.ui.container.Tab
        RecordyourpointsfromthefirsttothelastLabel  matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
        TypeparameterXEditFieldLabel  matlab.ui.control.Label
        inputx                        matlab.ui.control.EditField
        TypeparameterYLabel           matlab.ui.control.Label
        inputy                        matlab.ui.control.EditField
        TypepointnameLabel            matlab.ui.control.Label
        point_name                    matlab.ui.control.EditField
        OUTPUTTab                     matlab.ui.container.Tab
        ProcesseddataanditsresultsLabel  matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        gauge                         matlab.ui.control.Gauge
        ClearallButton                matlab.ui.control.Button
        PrintButton                   matlab.ui.control.Button
    end


    methods (Access = public)
    
        function FetchAllresults(app)
            
            Connector;
                  global colnames;
                   global data;
             colnames = { 'Beacon', 'Inputx', 'Inputy', 'From', 'To', 'Distance', 'Bearing' };
      
             selectquery = 'SELECT beacon, inputx, inputy,pfrom, pto, distance, bearing FROM coordinates order by id asc';
                     
               data = fetch(conn,selectquery);
             disp(data);
             
  uit = uitable(app.OUTPUTTab,'ColumnName',colnames,'Data',data);
  uit.FontSize = 14;
  uit.Position =[22 24 708 185];
  uit.BackgroundColor = [1 1 .9; .9 .95 1;1 .5 .5];    
        end


      function mycallback(app,src,uitevent)
%      display(uitevent.SelectedOption);
      confirm = uitevent.SelectedOption;
     if(confirm =="OK")
   
          Connector;
             deletequery = 'delete FROM coordinates';
                       %Empty database table
                      exec(conn,deletequery);
                     f = msgbox('Coordinate successfuly cleared', 'Cleared','info');
                      
                      %refresh after delete
                       FetchAllresults(app);
     else
     end
     
     
      end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                    
            % call the function that fetches all records   
             FetchAllresults(app);
             
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

%              Call outside script for database connection
             Connector;
             
            % Get textbox input  
          valuex = app.inputx.Value;
          valuey = app.inputy.Value; 
          valuepointname = app.point_name.Value;         
             
              %Textbox validation
               if(isempty(valuex))
                f=  errordlg('X, Text box can not be empty','Error');
             elseif (isempty(valuey))
                f = errordlg('Y, Text box can not be empty','Error');
             elseif (isempty(valuepointname))
                f = errordlg('Point name, Text box can not be empty','Error');
                
           elseif (isempty(str2num(valuex)) || isempty(str2num(valuey)))
                  f=  errordlg('X and Y, intputs must be numeric','Error');
               else
                   
             %collecting form data input      
                   
             %Write database 
              
               tablename = 'coordinates';
               mycolnames = { 'inputx', 'inputy', 'beacon', 'pfrom', 'pto', 'distance', 'bearing' };
               updatebleColumns = {'pfrom', 'pto', 'distance', 'bearing' };
               
               
              %Here you insert the data. 
                try 
                      
                       %check if coordinates already exist in db then update by calculating distance and bearing  
                       
                        %Find coordinates of every last row
                       selectquery = 'SELECT id, inputx,inputy,beacon FROM coordinates ORDER BY id DESC limit 1';
                       querry = select(conn,selectquery);
                       
                       %Find coordinates of the first row
                       fistrowSql = 'SELECT id, inputx,inputy,beacon FROM coordinates ORDER BY id ASC limit 1';
                       Firstquerry = select(conn,fistrowSql);
                       
                       %Count from database
                       myC = 'SELECT count(id) as counted  FROM coordinates';
                       countresult = select(conn,myC);
                        
                       
                       myquery = isempty(querry);
                       if(myquery)
                            
                           %when coordinates table is empty
                          insresValues = {valuex, valuey, valuepointname,'null', 'null', 0.0, 0.0 };
                          insert(conn, tablename, mycolnames, insresValues);
                                         
                          FetchAllresults(app);
                          f = msgbox('Coordinate successfuly saved', 'Success','info');
                   
                       else
 
                               %calculate distance   
                               
                               % fetch previous point from database
                               outx =str2double(querry.inputx);
                               outy=str2double(querry.inputy);
                               coord_id= querry.id;
                               lastfrom=char(valuepointname);
                               from =char(querry.beacon);
                              
                             
                                %subtract Next point from the previous point 
                              
                                    difx =str2double(valuex) - outx;
                                    dify= str2double(valuey) - outy;
                                    Distance =   sqrt( (difx)^2 + (dify)^2 );
                               
                               
                                 %Find last point and its distance  
                                 
                                  first_outx =str2double(Firstquerry.inputx);
                                  first_outy =str2double(Firstquerry.inputy);
                                  
                                  lastTo =char(Firstquerry.beacon);
                                
                                  
                                  mydifx =  first_outx - str2double(valuex);
                                  mydify =  first_outy - str2double(valuey);
                                  LastDistance =  sqrt((mydifx)^2 + (mydify)^2);
                                  cvalue = countresult.counted;
                                  Bearing = 'null';
                                  LastBearing = 'null'; 
                            if( cvalue ==1) % IF we only have one row in database

                                      
                                      if(difx > 0 && dify >0 )
                                           Bearing =radtodeg(atan (difx/dify));  %first quadrant
                            
                                      elseif(difx> 0 && dify <0)  %second quadrant
                               
                                         Bearing =180 + radtodeg(atan(difx/dify) );
                               
                                         elseif((difx)< 0 && (dify) <0) %Third quadrant
                              
                                         Bearing =180+ radtodeg(atan (difx/dify));
                                  
                                        elseif((difx) < 0 && (dify) > 0)  %Fourth quadrant
                                         Bearing = 360 - radtodeg(atan(difx/dify));
                                     end   
          
%                                    update previeous row 
                                     whereclause =['where id = ' sprintf('%f',coord_id)] ;
                                 dataforupdate = {from,valuepointname,Distance,Bearing};
                                 update(conn,tablename,updatebleColumns,dataforupdate,whereclause);
                                      
% %                                    Next insert form inputs
                                  insresValues = {valuex, valuey, valuepointname, 'null', 'null', 0.0, 0.0 };
                                  insert(conn, tablename, mycolnames, insresValues);
                                  
                                       FetchAllresults(app);
                                     f = msgbox('Coordinate successfuly saved', 'Success','info');
                                  
                          else
                                
                                      if(difx > 0 && dify >0 )
                                          Bearing =radtodeg(atan (difx/dify));  %first quadrant
                            
                                          elseif(difx > 0 && dify <0)  %second quadrant
                               
                                         Bearing =180 + radtodeg(atan(difx/dify) );
                               
                                         elseif(difx < 0 && dify <0) %Third quadrant
                              
                                         Bearing =180+ radtodeg(atan (difx/dify));
                                  
                                        elseif(difx < 0 && dify > 0)  %Fourth quadrant
                                         Bearing = 360 - radtodeg(atan(difx/dify));
                                        end   
                           
%                                  secondly ,update query distance , bearing etc for last row where id is for it
                                    whereclause =['where id = ' sprintf('%f',coord_id)] ;
                                    dataforupdate = {from,valuepointname,Distance,Bearing};
                                    update(conn,tablename,updatebleColumns,dataforupdate,whereclause);
                                
                                    
                                       if(mydifx > 0 && mydify >0 )
                                          LastBearing =radtodeg(atan (mydifx/mydify));  %first quadrant
                            
                                       elseif(mydifx> 0 && mydify <0)  %second quadrant
                               
                                         LastBearing =180 + radtodeg(atan(mydifx/mydify) );
                               
                                       elseif(mydifx< 0 && mydify <0) %Third quadrant
                              
                                         LastBearing =180+ radtodeg(atan (mydifx/mydify));
                                  
                                       elseif(mydifx < 0 && mydify > 0)  %Fourth quadrant
                                         LastBearing = 360 - radtodeg(atan(mydifx/mydify));
                                        end   
                                    
%                                   lastly, insert new row with the last distance calculation
                                  insresValues = {valuex, valuey, valuepointname, lastfrom, lastTo, LastDistance,LastBearing };
                                  insert(conn, tablename, mycolnames, insresValues);
                                  
                                        FetchAllresults(app);
                                     f = msgbox('Coordinate successfuly saved', 'Success','info');

                      end
           
                       end   
                catch e
              errordlg('Something went wrong ', 'Error');   
              disp(e);
                end
                
                %Clear form input 
                
               app.inputx.Value = '';
               app.inputy.Value = '';
               app.point_name.Value = '';
               
               end

        end

        % Callback function
        function SubmitButtonPushed2(app, event)
            
        end

        % Callback function
        function inputxValueChanged(app, event)
            valuex = app.inputx.Value;
        end

        % Callback function
        function inputyValueChanged(app, event)
            valuey = app.inputy.Value;
          
        end

        % Callback function
        function OUTPUTTabSizeChanged(app, event)
            position = app.OUTPUTTab.Position;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
             Connector;
                 
             selectquery = 'SELECT beacon, inputx,inputy,pfrom, pto, distance, bearing FROM coordinates order by id desc';   
             data = select(conn,selectquery);
            
             x = data.inputx;
             y = data.inputy;
             beacon =data.beacon;
                             
                  
                  allbeacon = convertCharsToStrings(beacon);
                  arrayx = [str2double(x)];
                  arrayy = [str2double(y)];

                  n = length(arrayx);
                 
%                   first  points (x and y) 
                    disp(arrayx(1)); % print the first number of array  x
                    disp(arrayy(1)); % print the first number of array  y
                
%                     last point (x and y)
                    disp(arrayx(n)); % print the last number of array  x
                    disp(arrayy(n)); % print the last number of array  y
                    
                       arraflx = [arrayx(1) arrayx(n)]; % Subtract the first coordinate from the last coordinate on x axes
                       arrafly = [arrayy(1) arrayy(n)]; % Subtract the first coordinate from the last coordinate on y axes
                  
                  
                       %Function for plotting coordinates
                      resultplot= plot(arrayx,arrayy,'.-r',arraflx,arrafly,'.-r', 'MarkerSize',15);
                    
                       
                      text(arrayx,arrayy, allbeacon, 'FontSize',12,'Interpreter','latex');  %Assigning beacons on the plot points position  
                      
                       set(gca,'visible', 'off'); %hide plot's axes     
                       set(gca, 'Position', [0.18, 0.07, 0.40, 0.40]); % position the plot on interface on right position 
                       set(gcf, 'Position', [100 100 900 600]) % set Figure window size 
       
                       
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)

       uit= uiconfirm(app.DBProcessorUIFigure,'Would you like to clear all coordinates ?','Confirm or cancel',...
         'CloseFcn',@(src,event)mycallback(app,src,event));
         
       
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
             
            
            global colnames; %access previously declared variable of table columns
            global data;     %access previously declared variable of data selected from database
            
            
            figure('Name','Deed Plan');    %Title of windows figure
          
%            imgheader= imread('deedplan_header.png'); 
%             imshow(imgheader);
%              set(gca, 'pos', [0.0, 0.74, 0.88, 0.20]); 
%              
%            imgfooter = imread('footer_deedplan.png'); 
%              imshow(imgfooter);
%              set(gca, 'Position', [0.0, 0.01, 0.97, 0.22]);


         %pass previously declared variables to the table to be printed
      uit = uitable('ColumnName',colnames,'Data',data);
      uit.FontSize = 12; 
%       uit.BackgroundColor = [1 1 .9; .9 .95 1;1 .5 .5];  
      set (uit,'ColumnWidth', {50 ,100,100,50,35,95,95});
      uit.Position =[150 320 558 190]; % fit the table on interface on right position where [margin left, margin bottom, width, height ]
      set(gcf, 'Position', [100 100 900 600]) % set Figure window size


   %      [X1,map1]=imread('deedplan_header.png');
   %      [X2,map2]=imread('footer_deedplan.png');
   %      subplot(1,2,1), imshow(X1,map1);
   %      subplot(1,2,5), imshow(X2,map2);
      
           Connector;     
   
            selectquery = 'SELECT beacon, inputx,inputy,pfrom, pto, distance, bearing FROM coordinates order by id desc';   
             alldata = select(conn,selectquery);
            
             x = alldata.inputx;
             y = alldata.inputy;
             beacon =alldata.beacon;
                             
                  
                  allbeacon = convertCharsToStrings(beacon);
                  arrayx = [str2double(x)];
                  arrayy = [str2double(y)];

                  n = length(arrayx);
                 
%                   first  points (x and y) 
                    disp(arrayx(1)); % print the first number of array  x
                    disp(arrayy(1)); % print the first number of array  y
                
%                     last point (x and y)
                    disp(arrayx(n)); % print the last number of array  x
                    disp(arrayy(n)); % print the last number of array  y
                    
                       arraflx = [arrayx(1) arrayx(n)]; % Subtract the first coordinate from the last coordinate on x axes
                       arrafly = [arrayy(1) arrayy(n)]; % Subtract the first coordinate from the last coordinate on y axes
                  
                  
                       %Function for plotting coordinates
                      resultplot= plot(arrayx,arrayy,'.-r',arraflx,arrafly,'.-r', 'MarkerSize',15);
                    
                       
                      text(arrayx,arrayy, allbeacon, 'FontSize',12,'Interpreter','latex');  %Assigning beacons on the plot points position  
                      
                       set(gca,'visible', 'off'); %hide plot's axes     
                       set(gca, 'Position', [0.18, 0.07, 0.40, 0.40]); % position the plot on interface on right position 


        end

        % Callback function
        function ButtonPushed3(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBProcessorUIFigure = uifigure;
            app.DBProcessorUIFigure.Name = 'DBProcessor';
            app.MATLABDATAPROCESSINGSYSTEMLabel = uilabel(app.DBProcessorUIFigure);
            app.MATLABDATAPROCESSINGSYSTEMLabel.Text = 'MATLAB DATA PROCESSING SYSTEM';
            app.TabGroup = uitabgroup(app.DBProcessorUIFigure);
            app.INPUTTab = uitab(app.TabGroup);
            app.INPUTTab.Title = 'INPUT';
            app.RecordyourpointsfromthefirsttothelastLabel = uilabel(app.INPUTTab);
            app.RecordyourpointsfromthefirsttothelastLabel.Text = 'Record your points from the first to the last';
            app.SubmitButton = uibutton(app.INPUTTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.TypeparameterXEditFieldLabel = uilabel(app.INPUTTab);
            app.TypeparameterXEditFieldLabel.Text = 'Type parameter X';
            app.inputx = uieditfield(app.INPUTTab, 'text');
            app.TypeparameterYLabel = uilabel(app.INPUTTab);
            app.TypeparameterYLabel.Text = 'Type parameter Y';
            app.inputy = uieditfield(app.INPUTTab, 'text');
            app.TypepointnameLabel = uilabel(app.INPUTTab);
            app.TypepointnameLabel.Text = '    Type point name';
            app.point_name = uieditfield(app.INPUTTab, 'text');
            app.OUTPUTTab = uitab(app.TabGroup);
            app.OUTPUTTab.Title = 'OUTPUT';
            app.ProcesseddataanditsresultsLabel = uilabel(app.OUTPUTTab);
            app.ProcesseddataanditsresultsLabel.Text = 'Processed data and its results';
            app.PlotButton = uibutton(app.OUTPUTTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.gauge = uigauge(app.OUTPUTTab, 'circular');
            app.gauge.Limits = [0 360];
            app.ClearallButton = uibutton(app.OUTPUTTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.PrintButton = uibutton(app.OUTPUTTab, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DBProcessor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBProcessorUIFigure)
        end
    end
end